classdef SLAM_PoseEstimation_Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        InSt_mid_ResultsPanel           matlab.ui.container.Panel
        AdvanceButton                   matlab.ui.control.Button
        kTextArea                       matlab.ui.control.TextArea
        KLabel                          matlab.ui.control.Label
        CalibStatusEditField            matlab.ui.control.EditField
        StoreIntrinsicsparametesButton  matlab.ui.control.Button
        EnterKmatrixasfx0px0fypy001EditField  matlab.ui.control.EditField
        EnterfilelocationEditField      matlab.ui.control.EditField
        EnterfilelocationLabel          matlab.ui.control.Label
        EnterparametersasfollowsFxFyPxPyWHAsLabel  matlab.ui.control.Label
        CalibDropDown                   matlab.ui.control.DropDown
        CameraInterinsicsParametersLabel  matlab.ui.control.Label
        InSt_ResultsPanel               matlab.ui.container.Panel
        VabsolutemsEditField_2          matlab.ui.control.NumericEditField
        VabsolutemsEditField_2Label     matlab.ui.control.Label
        VnorthmsEditField_2             matlab.ui.control.NumericEditField
        VnorthmsEditField_2Label        matlab.ui.control.Label
        VupmsEditField_2                matlab.ui.control.NumericEditField
        VupmsEditField_2Label           matlab.ui.control.Label
        VeastmsEditField_2              matlab.ui.control.NumericEditField
        VeastmsEditField_2Label         matlab.ui.control.Label
        LoopClosureCheckBox             matlab.ui.control.CheckBox
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        OutLog_ResultsPanel             matlab.ui.container.Panel
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        OutSt_ResultsPanel              matlab.ui.container.Panel
        VabsolutemsEditField            matlab.ui.control.NumericEditField
        VabsolutemsEditFieldLabel       matlab.ui.control.Label
        VnorthmsEditField               matlab.ui.control.NumericEditField
        VnorthmsEditFieldLabel          matlab.ui.control.Label
        VupmsEditField                  matlab.ui.control.NumericEditField
        VupmsEditFieldLabel             matlab.ui.control.Label
        VeastmsEditField                matlab.ui.control.NumericEditField
        VeastmsEditFieldLabel           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        InStr_OutLog_StatusPanel        matlab.ui.container.Panel
        OutLogStatusLamp                matlab.ui.control.Lamp
        LoggingStatusEditField          matlab.ui.control.EditField
        LoggingStatusEditFieldLabel     matlab.ui.control.Label
        GetLoggeddataButton             matlab.ui.control.Button
        EnterSensorlogmatfilelocationEditField  matlab.ui.control.EditField
        EnterSensorlogmatfilelocationLabel  matlab.ui.control.Label
        OutLogLabel                     matlab.ui.control.Label
        OutLog_DataPanel                matlab.ui.container.Panel
        UIAxes_OutLogGPSAcc             matlab.ui.control.UIAxes
        UIAxes_OutLogAlt                matlab.ui.control.UIAxes
        UIAxes_OutLogOrient             matlab.ui.control.UIAxes
        UIAxes_OutLogGyro               matlab.ui.control.UIAxes
        UIAxes_OutLogAcc                matlab.ui.control.UIAxes
        UIAxes_OutLogGPS                matlab.ui.control.UIAxes
        StartPanel                      matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        OutdoorDataDropDown             matlab.ui.control.DropDown
        LocalizationLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        OutSt_DataPanel                 matlab.ui.container.Panel
        HorizontalAccuracymEditField    matlab.ui.control.NumericEditField
        HorizontalAccuracymEditFieldLabel  matlab.ui.control.Label
        AltitudemEditField              matlab.ui.control.NumericEditField
        AltitudemEditFieldLabel         matlab.ui.control.Label
        LongitudedegEditField           matlab.ui.control.NumericEditField
        LongitudedegEditFieldLabel      matlab.ui.control.Label
        LatitudedegEditField            matlab.ui.control.NumericEditField
        LatitudedegEditFieldLabel       matlab.ui.control.Label
        UIAxes_OutStOrient              matlab.ui.control.UIAxes
        UIAxes_OutStGyro                matlab.ui.control.UIAxes
        UIAxes_OutStAcc                 matlab.ui.control.UIAxes
        InStr_StatusPanel               matlab.ui.container.Panel
        StreamingStatusLamp             matlab.ui.control.Lamp
        StreamingStatusEditField        matlab.ui.control.EditField
        StreamingStatusEditFieldLabel   matlab.ui.control.Label
        PauseStreamingButton            matlab.ui.control.Button
        StartStreamingButton            matlab.ui.control.Button
        StLabel                         matlab.ui.control.Label
        InSt_DataPanel                  matlab.ui.container.Panel
        UIAxes_InStCam                  matlab.ui.control.UIAxes
        UIAxes_InStOrient               matlab.ui.control.UIAxes
        UIAxes_InStGyro                 matlab.ui.control.UIAxes
        UIAxes_InStAcc                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LocalizationValue % Description
        DataValue
        OutDataValue
        Timer               timer
        MobileObject         
        StreamedAccelData
        tStreamedAccelData
        StreamedAv
        tStreamedAv
        StreamedAngle
        tStreamedAngle
        StreamedAccelDataOut
        tStreamedAccelDataOut
        StreamedAvOut
        tStreamedAvOut
        StreamedAngleOut
        tStreamedAngleOut
        StreamedPosOut
        tStreamedPosOut
        cam
        shot
        tshot
        latSt
        lonSt
        altSt
        tGPSSt
        horizaccSt
        latVectorSt = []
        lonVectorSt = []
        altVectorSt = []
        tGPSVectorSt = []
        horizaccVectorSt = []
        logDir
        loggedData
        calibValue
        calibEntered
        camIntrinsics
        kString = ""
        connectedFlag = 0
    end
    
    methods (Access = public)
        
        % Callback function to start streaming
        % This function Enables different sensors needed and starts logging 
        function startIndoorStreaming(app)
            % Create mobiledev object
            if app.connectedFlag == 0
               app.MobileObject = mobiledev; 
               app.connectedFlag = 1;
            end
            app.StreamingStatusEditField.Value = 'Connecting to a device...';
            % Enable accelerometer sensor
            app.MobileObject.AccelerationSensorEnabled = 1;
            % Enable Gyroscope sensor
            app.MobileObject.AngularVelocitySensorEnabled = 1;
            % Enable Orientation sensor
            app.MobileObject.OrientationSensorEnabled = 1;

            % Start logging data
            app.MobileObject.Logging = 1;
            app.StreamingStatusEditField.Value = 'Started Logging';
            
            % Set up the timer to call the updatePlot method every second

            if ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
            
            app.cam = camera(app.MobileObject,'back');

            % Create and start the new timer
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 0.1, ...  % Run the function every 0.1 second
                'TimerFcn', @(~,~)updateStreamingDashboard(app));
            start(app.Timer);

        end


        % Callback function to stop streaming
        function pauseOutdoorStreaming(app)
            % Stop the sensors, timer, and logging
            app.MobileObject.AccelerationSensorEnabled = 0;
            app.MobileObject.AngularVelocitySensorEnabled = 0;
            app.MobileObject.OrientationSensorEnabled = 0;
            app.MobileObject.PositionSensorEnabled = 0;
            app.MobileObject.Logging = 0;

            % Stop and delete the timer
            if ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end 
        end

        % Callback function to stop streaming
        function pauseIndoorStreaming(app)
            % Stop the sensors, timer, and logging
            app.MobileObject.AccelerationSensorEnabled = 0;
            app.MobileObject.AngularVelocitySensorEnabled = 0;
            app.MobileObject.PositionSensorEnabled = 0;
            app.MobileObject.Logging = 0;

            % Stop and delete the timer
            if ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
            %Stop and delete camera object
            if ~isempty(app.cam)
                app.cam = [];
            end        
        end

        % Callback function to start streaming
        % This function Enables different sensors needed and starts logging 
        function startOutdoorStreaming(app)
            % Create mobiledev object
            if app.connectedFlag == 0
               app.MobileObject = mobiledev; 
               app.connectedFlag = 1;
            end
            app.StreamingStatusEditField.Value = 'Connecting to a device...';
            % Enable accelerometer sensor
            app.MobileObject.AccelerationSensorEnabled = 1;
            % Enable Gyroscope sensor
            app.MobileObject.AngularVelocitySensorEnabled = 1;
            % Enable GPS sensor
            app.MobileObject.PositionSensorEnabled = 1;
            % Enable Orientation Sensor
            app.MobileObject.OrientationSensorEnabled = 1;

            % Start logging data
            app.MobileObject.Logging = 1;
            app.StreamingStatusEditField.Value = 'Started Logging';
            
            % Set up the timer to call the updatePlot method every second

            if ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
            
            % Create and start the new timer
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 0.1, ...  % Run the function every 0.1 second
                'TimerFcn', @(~,~)updateOutdoorStreamingDashboard(app));
            start(app.Timer);

        end

        % Method to update plot
        function updateOutdoorStreamingDashboard(app)
            app.StreamingStatusLamp.Color = 'g';
            % Get accelerometer data and timestamps
            [app.StreamedAccelDataOut, app.tStreamedAccelDataOut] = accellog(app.MobileObject);
            [app.StreamedAvOut,app.tStreamedAvOut] = angvellog(app.MobileObject);
            [app.StreamedAngleOut,app.tStreamedAngleOut] = orientlog(app.MobileObject);
            
            % Check if there's any data to plot
            %Accelerometer Data
            if ~isempty(app.StreamedAccelDataOut)
                plot(app.UIAxes_OutStAcc, app.tStreamedAccelDataOut, app.StreamedAccelDataOut);
                xlabel(app.UIAxes_OutStAcc, 'Time (s)');
                ylabel(app.UIAxes_OutStAcc, 'Acceleration (m/s^2)');
                legend(app.UIAxes_OutStAcc, 'A_x', 'A_y', 'A_z');
            end
            
            %Gyroscope Data
            if ~isempty(app.StreamedAvOut)
                plot(app.UIAxes_OutStGyro, app.tStreamedAvOut, app.StreamedAvOut);
                xlabel(app.UIAxes_OutStGyro, 'Time (s)');
                ylabel(app.UIAxes_OutStGyro, 'Angular Velocity (rad/s)');
                legend(app.UIAxes_OutStGyro, 'W_x', 'W_y', 'W_z');
            end

            % Orientation Data
            if ~isempty(app.StreamedAngleOut)
                plot(app.UIAxes_OutStOrient, app.tStreamedAngleOut, app.StreamedAngleOut);
                xlabel(app.UIAxes_OutStOrient, 'Time (s)');
                ylabel(app.UIAxes_OutStOrient, 'Angle (rad)');
                legend(app.UIAxes_OutStOrient, 'Theta_x', 'Theta_y', 'Theta_z');
            end


            %GPS Data            
            app.StreamingStatusEditField.Value = 'Obtaining data from GPS';
            [app.latSt, app.lonSt, ~, ~, ~, app.altSt, app.horizaccSt] = poslog(app.MobileObject);
            app.StreamingStatusEditField.Value = 'Obtained data from GPS';

            % Ensure latitude is a double scalar
            if ~isempty(app.latSt)
                app.LatitudedegEditField.Value = double(app.latSt(end));
            else
                app.LatitudedegEditField.Value = 0;
            end

            % Ensure longitude is a double scalar
            if ~isempty(app.lonSt)
                app.LongitudedegEditField.Value = double(app.lonSt(end));
            else
                app.LongitudedegEditField.Value = 0;
            end

            % Ensure altitude is a double scalar
            if ~isempty(app.altSt)
                app.AltitudemEditField.Value = double(app.altSt(end));
            else
                app.AltitudemEditField.Value = 0;
            end

            % Ensure horizontal accuracy is a double scalar
            if ~isempty(app.horizaccSt)
                app.HorizontalAccuracymEditField.Value = double(app.horizaccSt(end));
            else
                app.HorizontalAccuracymEditField.Value = 0;
            end
            app.StreamingStatusEditField.Value = 'Streaming';


        end

        % Method to update plot
        function updateStreamingDashboard(app)
            app.StreamingStatusLamp.Color = 'g';
            % Get accelerometer data and timestamps
            [app.StreamedAccelData, app.tStreamedAccelData] = accellog(app.MobileObject);
            [app.StreamedAv,app.tStreamedAv] = angvellog(app.MobileObject);
            [app.StreamedAngle,app.tStreamedAngle] = orientlog(app.MobileObject);
            
            % Check if there's any data to plot

            %Accelerometer Data

            if ~isempty(app.StreamedAccelData)
                % Plot the latest accelerometer data on UIAxes
                plot(app.UIAxes_InStAcc, app.tStreamedAccelData, app.StreamedAccelData);
                xlabel(app.UIAxes_InStAcc, 'Time (s)');
                ylabel(app.UIAxes_InStAcc, 'Acceleration (m/s^2)');
                legend(app.UIAxes_InStAcc, 'A_x', 'A_y', 'A_z');
            end
            
            %Gyroscope Data

            if ~isempty(app.StreamedAv)
                % Plot the latest accelerometer data on UIAxes
                plot(app.UIAxes_InStGyro, app.tStreamedAv, app.StreamedAv);
                xlabel(app.UIAxes_InStGyro, 'Time (s)');
                ylabel(app.UIAxes_InStGyro, 'Angular Velocity (rad/s)');
                legend(app.UIAxes_InStGyro, 'W_x', 'W_y', 'W_z');
            end

            % Orientation Data
            
            if ~isempty(app.StreamedAngle)
                % Plot the latest Orientation data on UIAxes
                plot(app.UIAxes_InStOrient, app.tStreamedAngle, app.StreamedAngle);
                xlabel(app.UIAxes_InStOrient, 'Time (s)');
                ylabel(app.UIAxes_InStOrient, 'Angle (rad)');
                legend(app.UIAxes_InStOrient, 'Theta_x', 'Theta_y', 'Theta_z');
            end

            app.StreamingStatusEditField.Value = 'Streaming';

            %Camera Data

            [app.shot, app.tshot] = snapshot(app.cam, 'immediate');
            imshow(app.shot, 'Parent', app.UIAxes_InStCam);

        end


        % Callback function to update dashboard with logged data
        function updateLogged(app)

            % Plot the accelerometer data on UIAxes
            plot(app.UIAxes_OutLogAcc, app.loggedData.Acceleration.Timestamp, [app.loggedData.Acceleration.X, app.loggedData.Acceleration.Y, app.loggedData.Acceleration.Z]);
            xlabel(app.UIAxes_OutLogAcc, 'Date');
            ylabel(app.UIAxes_OutLogAcc, 'Linear Acceleration (m/s^2)');
            legend(app.UIAxes_OutLogAcc, 'A_x', 'A_y', 'A_z');

            % Plot the GPS data on UIAxes
            plot(app.UIAxes_OutLogGPS, app.loggedData.Position.Timestamp, [app.loggedData.Position.latitude,app.loggedData.Position.longitude]);
            xlabel(app.UIAxes_OutLogGPS, 'Date');
            ylabel(app.UIAxes_OutLogGPS, 'GPS Lat/Lon (deg)');
            legend(app.UIAxes_OutLogGPS, 'Latitude', 'Longitude');


            % Plot the Gyro data on UIAxes
            plot(app.UIAxes_OutLogGyro, app.loggedData.AngularVelocity.Timestamp, [app.loggedData.AngularVelocity.X, app.loggedData.AngularVelocity.Y, app.loggedData.AngularVelocity.Z]);
            xlabel(app.UIAxes_OutLogGyro, 'Date');
            ylabel(app.UIAxes_OutLogGyro, 'Angular Velocity (rad/s)');
            legend(app.UIAxes_OutLogGyro, 'W_x', 'W_y', 'W_z');


            % Plot theOrientation data on UIAxes
            plot(app.UIAxes_OutLogOrient, app.loggedData.Orientation.Timestamp, [app.loggedData.Orientation.X, app.loggedData.Orientation.Y, app.loggedData.Orientation.Z]);
            xlabel(app.UIAxes_OutLogOrient, 'Date');
            ylabel(app.UIAxes_OutLogOrient, 'Angle (rad)');
            legend(app.UIAxes_OutLogOrient, 'Theta_x', 'Theta_y', 'Theta_z');

            % Plot the Altitude data on UIAxes
            plot(app.UIAxes_OutLogAlt, app.loggedData.Position.Timestamp, app.loggedData.Position.altitude);
            xlabel(app.UIAxes_OutLogAlt, 'Date');
            ylabel(app.UIAxes_OutLogAlt, 'Altitude (m)');

            % Plot the latest accelerometer data on UIAxes
            plot(app.UIAxes_OutLogGPSAcc, app.loggedData.Position.Timestamp, app.loggedData.Position.hacc);
            xlabel(app.UIAxes_OutLogGPSAcc, 'Date');
            ylabel(app.UIAxes_OutLogGPSAcc, 'GPS Accuracy (m)');

            app.LoggingStatusEditField.Value = "Logged data graphed.";
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Get screen size
            screenSize = get(0, 'ScreenSize');

            % Define app window size (width and height)
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);

            % Calculate the centered position
            centerX = (screenSize(3) - appWidth) / 2;
            centerY = (screenSize(4) - appHeight) / 2;

            % Set the new position of the figure
            app.UIFigure.Position = [centerX, centerY, appWidth, appHeight];

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            delete(app.UIFigure);
            eval([class(app) '()'])
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.LocalizationValue = app.DropDown.Value;
            if (strcmp(app.LocalizationValue, "1- Indoors Live Slam"))
                app.StartPanel.Position = [1,606,1096,88];
                app.InStr_StatusPanel.Visible = "on";
                app.InSt_DataPanel.Visible = "on";
                %app.InSt_ResultsPanel.Visible = "on";
                app.InSt_mid_ResultsPanel.Visible = "on";
                app.DropDown.Visible = "off";
                app.LocalizationLabel.Visible = "on";
                app.LocalizationLabel.Text = "[Indoor Live SLAM]";
            elseif (strcmp(app.LocalizationValue, "2- Outdoor Pose Estimation"))
                app.DropDown.Visible = "off";
                app.OutdoorDataDropDown.Visible = "on";
                app.InStr_StatusPanel.Visible = "off";
                app.InSt_DataPanel.Visible = "off";
                app.InSt_ResultsPanel.Visible = "off";
            end
        end

        % Button pushed function: StartStreamingButton
        function StartStreamingButtonPushed(app, event)
            app.StreamingStatusLamp.Color = [0.93,0.69,0.13];
            app.StreamingStatusEditField.Value = 'Starting to pair with a device ... ';
            if (strcmp(app.LocalizationValue, "1- Indoors Live Slam"))
                startIndoorStreaming(app);
            elseif (strcmp(app.LocalizationValue, "2- Outdoor Pose Estimation"))
                startOutdoorStreaming(app);
            end

        end

        % Button pushed function: PauseStreamingButton
        function PauseStreamingButtonPushed(app, event)
            app.StreamingStatusEditField.Value = 'Pausing the stream...';
            app.StreamingStatusLamp.Color = [0.93,0.69,0.13];
            if (strcmp(app.LocalizationValue, "1- Indoors Live Slam"))
                pauseIndoorStreaming(app);
            elseif (strcmp(app.LocalizationValue, "2- Outdoor Pose Estimation"))
                pauseOutdoorStreaming(app);
            end
            app.StreamingStatusEditField.Value = 'Streaming Paused.';
            app.StreamingStatusLamp.Color = 'r';
        end

        % Value changed function: OutdoorDataDropDown
        function OutdoorDataDropDownValueChanged(app, event)
            app.OutDataValue = app.OutdoorDataDropDown.Value;
            if (strcmp(app.OutDataValue, "1- Logged Data"))
                app.StartPanel.Position = [1,606,1096,88];
                app.InStr_StatusPanel.Visible = "off";
                app.InStr_OutLog_StatusPanel.Visible = "on";
                app.OutdoorDataDropDown.Visible = "off";
                app.OutLog_ResultsPanel.Visible = "on";
                app.OutLog_DataPanel.Visible = "on";
                app.LocalizationLabel.Visible = "on";
                app.LocalizationLabel.Text = "[Outdoor Pose Estimation for Logged data]";
            elseif (strcmp(app.OutDataValue, "2- Streaming Data"))
                app.StartPanel.Position = [1,606,1096,88];
                app.InStr_OutLog_StatusPanel.Visible = "off";
                app.InStr_StatusPanel.Visible = "on";
                app.OutdoorDataDropDown.Visible = "off";
                app.OutSt_ResultsPanel.Visible = "on";
                app.OutSt_DataPanel.Visible = "on";
                app.LocalizationLabel.Visible = "on";
                app.LocalizationLabel.Text = "[Outdoor Live Pose Estimation]";
            end
        end

        % Button pushed function: GetLoggeddataButton
        function GetLoggeddataButtonPushed(app, event)
            app.logDir = app.EnterSensorlogmatfilelocationEditField.Value;
            if isfile(app.logDir)
                app.LoggingStatusEditField.Value = 'Data Obtained.';
                app.OutLogStatusLamp.Color = "g";
                app.loggedData = load(app.logDir);
                pause(1);
                updateLogged(app);
            else
                app.LoggingStatusEditField.Value = 'No existing file, Check carefully!';
                app.OutLogStatusLamp.Color = "r";
            end

        end

        % Value changed function: CalibDropDown
        function CalibDropDownValueChanged(app, event)
            app.calibValue = app.CalibDropDown.Value;
            if (strcmp(app.calibValue, "Load from .mat file locally"))
                app.EnterKmatrixasfx0px0fypy001EditField.Visible = "off";
                app.EnterparametersasfollowsFxFyPxPyWHAsLabel.Visible = "off";
                app.EnterfilelocationEditField.Visible = "on";
                app.EnterfilelocationLabel.Visible = "on";
                app.StoreIntrinsicsparametesButton.Visible = "on";
            elseif (strcmp(app.calibValue, "Enter Parameters manually"))
                app.EnterKmatrixasfx0px0fypy001EditField.Visible = "on";
                app.EnterparametersasfollowsFxFyPxPyWHAsLabel.Visible = "on";
                app.EnterfilelocationEditField.Visible = "off";
                app.EnterfilelocationLabel.Visible = "off";
                app.StoreIntrinsicsparametesButton.Visible = "on";
            end
        end

        % Button pushed function: StoreIntrinsicsparametesButton
        function StoreIntrinsicsparametesButtonPushed(app, event)
            app.CalibStatusEditField.Visible = "on";
            app.kTextArea.Value = "";
            app.kString = "";
            if (strcmp(app.calibValue, "Load from .mat file locally"))
                if (isfile(app.EnterfilelocationEditField.Value))
                    app.camIntrinsics = load(app.EnterfilelocationEditField.Value).cameraParams.Intrinsics;
                    app.CalibStatusEditField.Value = "Obtained Parameters from file.";
                    app.kTextArea.Visible = "on";
                    app.KLabel.Visible = "on";
                    app.AdvanceButton.Visible = "on";
                    for i = 1:size(app.camIntrinsics.K, 1)
                        rowStr = sprintf('%-22.6f ', app.camIntrinsics.K(i, :));  % Convert row to string with formatting
                        app.kString = append(app.kString,rowStr,newline);             % Append to the main string with newline
                    end
                    app.kTextArea.Value = app.kString;
                else
                    app.CalibStatusEditField.Value = "Invalid file path, check again!";
                    app.kTextArea.Value = "";
                end
            elseif (strcmp(app.calibValue, "Enter Parameters manually"))
                if (length(str2num(app.EnterKmatrixasfx0px0fypy001EditField.Value)) == 6)
                    app.CalibStatusEditField.Value = "Parameters entered are stored.";
                    app.calibEntered = str2num(app.EnterKmatrixasfx0px0fypy001EditField.Value);
                    app.camIntrinsics = cameraIntrinsics([app.calibEntered(1), app.calibEntered(2)], [app.calibEntered(3), app.calibEntered(4)], [app.calibEntered(5), app.calibEntered(6)]);
                    app.kTextArea.Visible = "on";
                    app.KLabel.Visible = "on";
                    for i = 1:size(app.camIntrinsics.K, 1)
                        rowStr = sprintf('%-22.6f ', app.camIntrinsics.K(i, :));  % Convert row to string with formatting
                        app.kString = append(app.kString,rowStr,newline);             % Append to the main string with newline
                    end
                    app.kTextArea.Value = app.kString;
                else
                    app.CalibStatusEditField.Value = "Invalid format, check again!";
                    app.kTextArea.Value = "";
                end
                app.kString = "";
                app.AdvanceButton.Visible = "on";
            end
        end

        % Button pushed function: AdvanceButton
        function AdvanceButtonPushed(app, event)
            app.InSt_mid_ResultsPanel.Visible = "off";
            app.InSt_ResultsPanel.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.InSt_DataPanel = uipanel(app.Panel);
            app.InSt_DataPanel.Title = 'Live Streamed Data';
            app.InSt_DataPanel.Visible = 'off';
            app.UIAxes_InStAcc = uiaxes(app.InSt_DataPanel);
            title(app.UIAxes_InStAcc, 'Linear Acceleration')
            xlabel(app.UIAxes_InStAcc, 'Time (s)')
            ylabel(app.UIAxes_InStAcc, 'Acceleration (m/s^2)')
            app.UIAxes_InStGyro = uiaxes(app.InSt_DataPanel);
            title(app.UIAxes_InStGyro, 'Angular Velocity')
            xlabel(app.UIAxes_InStGyro, 'Time (s)')
            ylabel(app.UIAxes_InStGyro, 'Angular Velocity (rad/s)')
            app.UIAxes_InStOrient = uiaxes(app.InSt_DataPanel);
            title(app.UIAxes_InStOrient, 'Orientation')
            xlabel(app.UIAxes_InStOrient, 'Time (s)')
            ylabel(app.UIAxes_InStOrient, 'Orientation (rad)')
            app.UIAxes_InStCam = uiaxes(app.InSt_DataPanel);
            title(app.UIAxes_InStCam, 'Camera')
            app.UIAxes_InStCam.XTick = [];
            app.UIAxes_InStCam.YTick = [];
            app.InStr_StatusPanel = uipanel(app.Panel);
            app.InStr_StatusPanel.Visible = 'off';
            app.StLabel = uilabel(app.InStr_StatusPanel);
            app.StLabel.Text = 'Make Sure Matlab mobile application is opened and (Stream to) option in settings is set to MATLAB';
            app.StartStreamingButton = uibutton(app.InStr_StatusPanel, 'push');
            app.StartStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @StartStreamingButtonPushed, true);
            app.StartStreamingButton.Text = 'Start Streaming';
            app.PauseStreamingButton = uibutton(app.InStr_StatusPanel, 'push');
            app.PauseStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @PauseStreamingButtonPushed, true);
            app.PauseStreamingButton.Text = 'Pause Streaming';
            app.StreamingStatusEditFieldLabel = uilabel(app.InStr_StatusPanel);
            app.StreamingStatusEditFieldLabel.Text = 'Streaming Status:';
            app.StreamingStatusEditField = uieditfield(app.InStr_StatusPanel, 'text');
            app.StreamingStatusEditField.Value = 'Ready to start Streaming ...';
            app.StreamingStatusLamp = uilamp(app.InStr_StatusPanel);
            app.OutSt_DataPanel = uipanel(app.Panel);
            app.OutSt_DataPanel.Title = 'Live Data';
            app.OutSt_DataPanel.Visible = 'off';
            app.UIAxes_OutStAcc = uiaxes(app.OutSt_DataPanel);
            title(app.UIAxes_OutStAcc, 'Linear Acceleration')
            xlabel(app.UIAxes_OutStAcc, 'Time (s)')
            ylabel(app.UIAxes_OutStAcc, 'Linear Acceleration (m/s^2)')
            zlabel(app.UIAxes_OutStAcc, 'Z')
            app.UIAxes_OutStGyro = uiaxes(app.OutSt_DataPanel);
            title(app.UIAxes_OutStGyro, 'Angular Velocity')
            xlabel(app.UIAxes_OutStGyro, 'Time (s)')
            ylabel(app.UIAxes_OutStGyro, 'Angular Velocity (rad/s)')
            zlabel(app.UIAxes_OutStGyro, 'Z')
            app.UIAxes_OutStOrient = uiaxes(app.OutSt_DataPanel);
            title(app.UIAxes_OutStOrient, 'Orientation')
            xlabel(app.UIAxes_OutStOrient, 'Time (s)')
            ylabel(app.UIAxes_OutStOrient, 'Orientation (rad)')
            zlabel(app.UIAxes_OutStOrient, 'Z')
            app.LatitudedegEditFieldLabel = uilabel(app.OutSt_DataPanel);
            app.LatitudedegEditFieldLabel.Text = 'Latitude (deg)';
            app.LatitudedegEditField = uieditfield(app.OutSt_DataPanel, 'numeric');
            app.LongitudedegEditFieldLabel = uilabel(app.OutSt_DataPanel);
            app.LongitudedegEditFieldLabel.Text = 'Longitude (deg)';
            app.LongitudedegEditField = uieditfield(app.OutSt_DataPanel, 'numeric');
            app.AltitudemEditFieldLabel = uilabel(app.OutSt_DataPanel);
            app.AltitudemEditFieldLabel.Text = 'Altitude (m)';
            app.AltitudemEditField = uieditfield(app.OutSt_DataPanel, 'numeric');
            app.HorizontalAccuracymEditFieldLabel = uilabel(app.OutSt_DataPanel);
            app.HorizontalAccuracymEditFieldLabel.Text = 'Horizontal Accuracy (m)';
            app.HorizontalAccuracymEditField = uieditfield(app.OutSt_DataPanel, 'numeric');
            app.StartPanel = uipanel(app.Panel);
            app.Image = uiimage(app.StartPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.gif');
            app.Label = uilabel(app.StartPanel);
            app.Label.Text = 'Mobile Sensory data dashboard for indoor SLAM and outdoor Pose estimation';
            app.LocalizationLabel = uilabel(app.StartPanel);
            app.LocalizationLabel.Visible = 'off';
            app.OutdoorDataDropDown = uidropdown(app.StartPanel);
            app.OutdoorDataDropDown.Items = {'Data Obtaining Method for Outdoor Localization', '1- Logged Data', '2- Streaming Data'};
            app.OutdoorDataDropDown.ValueChangedFcn = createCallbackFcn(app, @OutdoorDataDropDownValueChanged, true);
            app.OutdoorDataDropDown.Visible = 'off';
            app.OutdoorDataDropDown.Value = 'Data Obtaining Method for Outdoor Localization';
            app.DropDown = uidropdown(app.StartPanel);
            app.DropDown.Items = {'Choose Localization Method', '1- Indoors Live Slam', '2- Outdoor Pose Estimation'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Choose Localization Method';
            app.ResetButton = uibutton(app.StartPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutLog_DataPanel = uipanel(app.Panel);
            app.OutLog_DataPanel.Title = 'Logged Data';
            app.OutLog_DataPanel.Visible = 'off';
            app.UIAxes_OutLogGPS = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogGPS, 'GPS (Latitude / Longitude)')
            xlabel(app.UIAxes_OutLogGPS, 'Time (s)')
            ylabel(app.UIAxes_OutLogGPS, 'GPS Lat/Lon (deg)')
            zlabel(app.UIAxes_OutLogGPS, 'Z')
            app.UIAxes_OutLogAcc = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogAcc, 'Linear Acceleration')
            xlabel(app.UIAxes_OutLogAcc, 'Time (s)')
            ylabel(app.UIAxes_OutLogAcc, 'Linear Acceleration (m/s^2)')
            zlabel(app.UIAxes_OutLogAcc, 'Z')
            app.UIAxes_OutLogGyro = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogGyro, 'Angular Velocity')
            xlabel(app.UIAxes_OutLogGyro, 'Time (s)')
            ylabel(app.UIAxes_OutLogGyro, 'Angular Velocity (rad/s)')
            zlabel(app.UIAxes_OutLogGyro, 'Z')
            app.UIAxes_OutLogOrient = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogOrient, 'Orientation')
            xlabel(app.UIAxes_OutLogOrient, 'Time (s)')
            ylabel(app.UIAxes_OutLogOrient, 'Orientation (rad)')
            zlabel(app.UIAxes_OutLogOrient, 'Z')
            app.UIAxes_OutLogAlt = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogAlt, 'GPS (Altitude)')
            xlabel(app.UIAxes_OutLogAlt, 'Time (s)')
            ylabel(app.UIAxes_OutLogAlt, 'Latitude (m)')
            zlabel(app.UIAxes_OutLogAlt, 'Z')
            app.UIAxes_OutLogGPSAcc = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogGPSAcc, 'GPS Horizontal Accuracy ')
            xlabel(app.UIAxes_OutLogGPSAcc, 'Time (s)')
            ylabel(app.UIAxes_OutLogGPSAcc, 'GPS Accuracy (m)')
            zlabel(app.UIAxes_OutLogGPSAcc, 'Z')
            app.InStr_OutLog_StatusPanel = uipanel(app.Panel);
            app.InStr_OutLog_StatusPanel.Visible = 'off';
            app.OutLogLabel = uilabel(app.InStr_OutLog_StatusPanel);
            app.OutLogLabel.Text = 'Make Sure Matlab-Drive is connected locally, and Logged data contain the following data (Linear Acceleration, Angular Velocity, Orientation, and GPS Position)';
            app.EnterSensorlogmatfilelocationLabel = uilabel(app.InStr_OutLog_StatusPanel);
            app.EnterSensorlogmatfilelocationLabel.Text = 'Enter Sensorlog (.mat) file location: ';
            app.EnterSensorlogmatfilelocationEditField = uieditfield(app.InStr_OutLog_StatusPanel, 'text');
            app.GetLoggeddataButton = uibutton(app.InStr_OutLog_StatusPanel, 'push');
            app.GetLoggeddataButton.ButtonPushedFcn = createCallbackFcn(app, @GetLoggeddataButtonPushed, true);
            app.GetLoggeddataButton.Text = 'Get Logged data';
            app.LoggingStatusEditFieldLabel = uilabel(app.InStr_OutLog_StatusPanel);
            app.LoggingStatusEditFieldLabel.Text = 'Logging Status:';
            app.LoggingStatusEditField = uieditfield(app.InStr_OutLog_StatusPanel, 'text');
            app.LoggingStatusEditField.Value = 'Ready to start Streaming ...';
            app.OutLogStatusLamp = uilamp(app.InStr_OutLog_StatusPanel);
            app.OutSt_ResultsPanel = uipanel(app.Panel);
            app.OutSt_ResultsPanel.Title = 'Outdoor Live Pose Estimation';
            app.OutSt_ResultsPanel.Visible = 'off';
            app.UIAxes2 = uiaxes(app.OutSt_ResultsPanel);
            title(app.UIAxes2, 'Local Trajectory')
            xlabel(app.UIAxes2, 'East')
            ylabel(app.UIAxes2, 'North')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.OutSt_ResultsPanel);
            title(app.UIAxes, 'worldmap Global Trajectory')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.VeastmsEditFieldLabel = uilabel(app.OutSt_ResultsPanel);
            app.VeastmsEditFieldLabel.Text = 'V east (m/s)';
            app.VeastmsEditField = uieditfield(app.OutSt_ResultsPanel, 'numeric');
            app.VupmsEditFieldLabel = uilabel(app.OutSt_ResultsPanel);
            app.VupmsEditFieldLabel.Text = 'V up (m/s)';
            app.VupmsEditField = uieditfield(app.OutSt_ResultsPanel, 'numeric');
            app.VnorthmsEditFieldLabel = uilabel(app.OutSt_ResultsPanel);
            app.VnorthmsEditFieldLabel.Text = 'V north (m/s)';
            app.VnorthmsEditField = uieditfield(app.OutSt_ResultsPanel, 'numeric');
            app.VabsolutemsEditFieldLabel = uilabel(app.OutSt_ResultsPanel);
            app.VabsolutemsEditFieldLabel.Text = 'V absolute (m/s)';
            app.VabsolutemsEditField = uieditfield(app.OutSt_ResultsPanel, 'numeric');
            app.OutLog_ResultsPanel = uipanel(app.Panel);
            app.OutLog_ResultsPanel.Title = 'Outdoor Logged data pose estimation';
            app.OutLog_ResultsPanel.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.OutLog_ResultsPanel);
            title(app.UIAxes_2, 'worldmap Global Trajectory')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.OutLog_ResultsPanel);
            title(app.UIAxes2_2, 'Local Trajectory')
            xlabel(app.UIAxes2_2, 'East')
            ylabel(app.UIAxes2_2, 'North')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3 = uiaxes(app.OutLog_ResultsPanel);
            title(app.UIAxes3, 'Local ENU Velocities')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Velocity (m/s)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.OutLog_ResultsPanel);
            title(app.UIAxes3_2, 'Absolute Velocity')
            xlabel(app.UIAxes3_2, 'Time (s)')
            ylabel(app.UIAxes3_2, 'V_(abs) (m/s)')
            zlabel(app.UIAxes3_2, 'Z')
            app.InSt_ResultsPanel = uipanel(app.Panel);
            app.InSt_ResultsPanel.Title = 'Indoor Live SLAM';
            app.InSt_ResultsPanel.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.InSt_ResultsPanel);
            title(app.UIAxes_3, 'Features Map using monovslam')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes2_3 = uiaxes(app.InSt_ResultsPanel);
            title(app.UIAxes2_3, 'Local Trajectory')
            xlabel(app.UIAxes2_3, 'East')
            ylabel(app.UIAxes2_3, 'North')
            zlabel(app.UIAxes2_3, 'Z')
            app.LoopClosureCheckBox = uicheckbox(app.InSt_ResultsPanel);
            app.LoopClosureCheckBox.Text = 'Loop Closure';
            app.VeastmsEditField_2Label = uilabel(app.InSt_ResultsPanel);
            app.VeastmsEditField_2Label.Text = 'V east (m/s)';
            app.VeastmsEditField_2 = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.VupmsEditField_2Label = uilabel(app.InSt_ResultsPanel);
            app.VupmsEditField_2Label.Text = 'V up (m/s)';
            app.VupmsEditField_2 = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.VnorthmsEditField_2Label = uilabel(app.InSt_ResultsPanel);
            app.VnorthmsEditField_2Label.Text = 'V north (m/s)';
            app.VnorthmsEditField_2 = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.VabsolutemsEditField_2Label = uilabel(app.InSt_ResultsPanel);
            app.VabsolutemsEditField_2Label.Text = 'V absolute (m/s)';
            app.VabsolutemsEditField_2 = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.InSt_mid_ResultsPanel = uipanel(app.Panel);
            app.InSt_mid_ResultsPanel.Title = 'Indoor Live SLAM';
            app.InSt_mid_ResultsPanel.Visible = 'off';
            app.CameraInterinsicsParametersLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.CameraInterinsicsParametersLabel.Text = 'Camera Interinsics Parameters';
            app.CalibDropDown = uidropdown(app.InSt_mid_ResultsPanel);
            app.CalibDropDown.Items = {'Method to get Intrinsics camera Params', 'Load from .mat file locally', 'Enter Parameters manually'};
            app.CalibDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibDropDownValueChanged, true);
            app.CalibDropDown.Value = 'Method to get Intrinsics camera Params';
            app.EnterparametersasfollowsFxFyPxPyWHAsLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.EnterparametersasfollowsFxFyPxPyWHAsLabel.Visible = 'off';
            app.EnterparametersasfollowsFxFyPxPyWHAsLabel.Text = {'Enter parameters as follows [Fx Fy Px Py W H] :'; 'As Fx, Fy -> Focal length'; 'Px, Py -> Principal Points'; 'W, H -> image width and height respectively'};
            app.EnterfilelocationLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.EnterfilelocationLabel.Visible = 'off';
            app.EnterfilelocationLabel.Text = 'Enter file location:';
            app.EnterfilelocationEditField = uieditfield(app.InSt_mid_ResultsPanel, 'text');
            app.EnterfilelocationEditField.Visible = 'off';
            app.EnterKmatrixasfx0px0fypy001EditField = uieditfield(app.InSt_mid_ResultsPanel, 'text');
            app.EnterKmatrixasfx0px0fypy001EditField.Visible = 'off';
            app.StoreIntrinsicsparametesButton = uibutton(app.InSt_mid_ResultsPanel, 'push');
            app.StoreIntrinsicsparametesButton.ButtonPushedFcn = createCallbackFcn(app, @StoreIntrinsicsparametesButtonPushed, true);
            app.StoreIntrinsicsparametesButton.Visible = 'off';
            app.StoreIntrinsicsparametesButton.Text = 'Store Intrinsics parametes';
            app.CalibStatusEditField = uieditfield(app.InSt_mid_ResultsPanel, 'text');
            app.CalibStatusEditField.Visible = 'off';
            app.KLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.KLabel.Visible = 'off';
            app.KLabel.Text = 'K = ';
            app.kTextArea = uitextarea(app.InSt_mid_ResultsPanel);
            app.kTextArea.Visible = 'off';
            app.AdvanceButton = uibutton(app.InSt_mid_ResultsPanel, 'push');
            app.AdvanceButton.ButtonPushedFcn = createCallbackFcn(app, @AdvanceButtonPushed, true);
            app.AdvanceButton.Visible = 'off';
            app.AdvanceButton.Text = 'Advance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLAM_PoseEstimation_Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end